classdef Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FormationShapeGeneratorUIFigure  matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        GridScaleButtonGroup   matlab.ui.container.ButtonGroup
        sparseButton           matlab.ui.control.RadioButton
        normalButton           matlab.ui.control.RadioButton
        denseButton            matlab.ui.control.RadioButton
        BrushSizeButtonGroup   matlab.ui.container.ButtonGroup
        smallButton            matlab.ui.control.RadioButton
        mediumButton           matlab.ui.control.RadioButton
        bigButton              matlab.ui.control.RadioButton
        GrayLevelButtonGroup   matlab.ui.container.ButtonGroup
        EditField              matlab.ui.control.NumericEditField
        SettingButton          matlab.ui.control.StateButton
        BrushColorButtonGroup  matlab.ui.container.ButtonGroup
        blackButton            matlab.ui.control.RadioButton
        whiteButton            matlab.ui.control.RadioButton
        BuildingButton         matlab.ui.control.Button
        ClearImageButton       matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
    end

    % Variables that correspond to the app
    properties (Access = private)
        % Set parameters
        grid_scale=0;          % 0:normal, -1:sparse, 1:dense
        grid_sparse=20;
        grid_normal=60;
        grid_dense=100;
        brush_size=0;          % 0:medium, -1:small, 1:big
        brush_value=0;
        brush_small=1;
        brush_medium=5;
        brush_big=10;
        brush_color=0;         % 0:black, 1:white
        gray_level=10;
        % Initial states
        scn_width=0;
        scn_height=0; 
        axes_minx=0;
        axes_miny=0;
        axes_width=0;
        axes_height=0;  
        image_matrix=0;
        point_pre=[-1,-1];
        % Operate flag
        mouse_down=0;
    end
    
    % Functions that correspond to the app
    methods (Access = private)
        % Set axes grid distribution
        function setAxesGridFcn(app)
            if app.grid_scale==-1
                grid_value=app.grid_sparse;
            elseif app.grid_scale==1
                grid_value=app.grid_dense;
            else
                grid_value=app.grid_normal;
            end 
            app.brush_medium=grid_value/20;
            app.brush_big=grid_value/10;
            app.UIAxes.XLim=[0,grid_value];
            app.UIAxes.YLim=[0,grid_value];
            app.UIAxes.XTick=0:1:grid_value;
            app.UIAxes.YTick=0:1:grid_value;
            app.image_matrix=ones(grid_value,grid_value);
            app.UIAxes.cla;
        end
        
        % Get grid coordinates
        function updateImageInfoFcn(app,point)
            temp=round(app.brush_value/2)-1;
            rect=[floor(point(1))-temp,floor(point(2))-temp,app.brush_value,app.brush_value];
            size=length(app.image_matrix(1,:));
            max_x=size-max(ceil(point(2))-temp,1)+1;
            min_x=size-min(ceil(point(2))+temp,size)+1;
            min_y=max(ceil(point(1))-temp,1);
            max_y=min(ceil(point(1))+temp,size);
            if app.brush_color==0
                rectangle(app.UIAxes,'Position',rect,'FaceColor','k','EdgeColor','none');
                app.image_matrix(min_x:max_x,min_y:max_y)=0;
            else
                rectangle(app.UIAxes,'Position',rect,'FaceColor','w','EdgeColor','none');
                app.image_matrix(min_x:max_x,min_y:max_y)=1;
            end
        end
        
        % Set brush size
        function setBrushSizeFcn(app)
            if app.brush_size==-1
                app.brush_value=app.brush_small;
            elseif app.brush_size==1
                app.brush_value=app.brush_big;
            else
                app.brush_value=app.brush_medium;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get screen size
            scn_size=get(0,'ScreenSize');
            app.scn_width=scn_size(3);
            app.scn_height=scn_size(4); 
           
            % Set window size            
            win_height=600; 
            win_width=600/0.8;                       
            win_minx=app.scn_width/2-win_width/2;
            win_miny=app.scn_height/2-win_height/2;
            app.axes_minx=win_minx;
            app.axes_miny=win_miny;
            app.axes_width=win_width;
            app.axes_height=win_height;
            app.FormationShapeGeneratorUIFigure.Position=[win_minx,win_miny,win_width,win_height];
            
            % Set axes size
            border=10;
            axs_side=win_height-2*border;
            app.UIAxes.Position=[border,border,axs_side,axs_side];
            
            % Set grid-scale size
            grp_width=win_width-3*border-axs_side-2;
            grp_height=axs_side/5.0-border;
            grp_minx=2*border+axs_side-2;
            grp_miny=win_height-border-grp_height-5;
            app.GridScaleButtonGroup.Position=[grp_minx,grp_miny,grp_width,grp_height];
            % Set group buttons
            delta=8;
            ele_width=grp_width-4*delta;
            ele_height=(grp_height-6*delta)/3;
            app.denseButton.InnerPosition=[2*delta,1.5*delta,ele_width,ele_height];
            app.normalButton.InnerPosition=[2*delta,1.8*delta+ele_height,ele_width,ele_height];
            app.sparseButton.InnerPosition=[2*delta,2.1*delta+2*ele_height,ele_width,ele_height];
            
            % Set brush-size size
            grp_miny=grp_miny-(border+grp_height);
            app.BrushSizeButtonGroup.Position=[grp_minx,grp_miny,grp_width,grp_height];
            % Set group buttons
            delta=8;
            ele_width=grp_width-4*delta;
            ele_height=(grp_height-6*delta)/3;
            app.bigButton.InnerPosition=[2*delta,1.5*delta,ele_width,ele_height];
            app.mediumButton.InnerPosition=[2*delta,1.8*delta+ele_height,ele_width,ele_height];
            app.smallButton.InnerPosition=[2*delta,2.1*delta+2*ele_height,ele_width,ele_height];
            
            % Set brush-color size  
            grp_height=grp_height*0.75;
            grp_miny=grp_miny-(border+grp_height);            
            app.BrushColorButtonGroup.Position=[grp_minx,grp_miny,grp_width,grp_height];
            % Set group buttons
            delta=8;
            ele_height=(grp_height-6*delta)/2;
            app.whiteButton.InnerPosition=[2*delta,1.5*delta,ele_width,ele_height];
            app.blackButton.InnerPosition=[2*delta,2.0*delta+ele_height,ele_width,ele_height];
            
            % Set gray-level size   
            grp_height=grp_height/0.75;
            grp_miny=grp_miny-(border+grp_height);            
            app.GrayLevelButtonGroup.Position=[grp_minx,grp_miny,grp_width,grp_height];
            % Set group buttons            
            delta=8;
            ele_width=grp_width-2*delta;
            ele_height=(grp_height-6*delta)/2;
            app.SettingButton.InnerPosition=[1*delta,1.2*delta,ele_width,ele_height];
            app.EditField.InnerPosition=[1*delta,2.2*delta+ele_height,ele_width,ele_height];
            
            % Set load-button size   
            temp=grp_miny/3-1.5*border;
            grp_height=temp*0.9;
            grp_miny=grp_miny-(border+grp_height);            
            app.ClearImageButton.Position=[grp_minx,grp_miny,grp_width,grp_height];
            grp_miny=grp_miny-(border+grp_height);            
            app.LoadImageButton.Position=[grp_minx,grp_miny,grp_width,grp_height];
            
            % Set Build-button size   
            grp_height=temp*1.2;
            grp_miny=grp_miny-(border+grp_height);            
            app.BuildingButton.Position=[grp_minx,grp_miny,grp_width,grp_height];
            
            % Set axes property
            app.UIAxes.Toolbar.Visible='off';
            app.UIAxes.Interactions=[];
            app.UIAxes.GridColor=[0.8,0.8,0.8];
            app.UIAxes.LineWidth=0.5;
            
            % Set grid-scale property
            app.GridScaleButtonGroup.FontSize=14;
            if app.grid_scale==-1
                app.sparseButton.Value=true;
            elseif app.grid_scale==1
                app.denseButton.Value=true;
            else
                app.normalButton.Value=true;
            end
            
            % Set brush-size property
            app.BrushSizeButtonGroup.FontSize=14;
            if app.brush_size==-1
                app.smallButton.Value=true;
            elseif app.brush_size==1
                app.bigButton.Value=true;
            else
                app.mediumButton.Value=true;
            end
            
            % Set brush-color property
            app.BrushColorButtonGroup.FontSize=14;
            if app.brush_color==0
                app.blackButton.Value=true;
            else
                app.whiteButton.Value=true;
            end
            
            % Set gray-level property
            app.GrayLevelButtonGroup.FontSize=14;
            app.EditField.Value=app.gray_level;
            
            % Set axes grid
            setAxesGridFcn(app);
            setBrushSizeFcn(app);
        end

        % Selection changed function: GridScaleButtonGroup
        function GridScaleButtonGroupSelectionChanged(app, event)
            %selectedButton = app.GridScaleButtonGroup.SelectedObject;
            if app.sparseButton.Value
                app.grid_scale=-1;                
            elseif app.denseButton.Value
                app.grid_scale=1;
            else
                app.grid_scale=0;
            end
            setAxesGridFcn(app);
        end

        % Window button down function: 
        % FormationShapeGeneratorUIFigure
        function FormationShapeGeneratorUIFigureWindowButtonDown(app, event)
            app.mouse_down=1;
            app.point_pre=app.UIAxes.CurrentPoint(1,1:2);
            updateImageInfoFcn(app,app.point_pre);
        end

        % Window button motion function: 
        % FormationShapeGeneratorUIFigure
        function FormationShapeGeneratorUIFigureWindowButtonMotion(app, event)
            if app.mouse_down                
                point_cur=app.UIAxes.CurrentPoint(1,1:2);
                error_x=point_cur(1)-app.point_pre(1);
                error_y=point_cur(2)-app.point_pre(2);
                alpha=atan2(error_y,error_x);
                dist=sqrt(error_x^2+error_y^2);
                cos_alpha=cos(alpha);
                temp=sqrt(2)/2;
                if cos_alpha>=-temp&&cos_alpha<=temp
                    delta=abs(sin(alpha));      
                else
                    delta=abs(1/cos_alpha);
                end
                num=ceil(dist/delta);
                for i=1:1:num
                    if i==num
                        updateImageInfoFcn(app,point_cur);
                    else
                        point_temp=[app.point_pre(1),app.point_pre(2)]+i*[error_x,error_y]/num;
                        updateImageInfoFcn(app,point_temp);
                    end
                end                
                app.point_pre=point_cur;
            end
        end

        % Selection changed function: BrushColorButtonGroup
        function BrushColorButtonGroupSelectionChanged(app, event)
            %selectedButton = app.BrushColorButtonGroup.SelectedObject;
            if app.blackButton.Value
                app.brush_color=0;
            else
                app.brush_color=1;
            end
        end

        % Window button up function: FormationShapeGeneratorUIFigure
        function FormationShapeGeneratorUIFigureWindowButtonUp(app, event)
            app.mouse_down=0;
        end

        % Selection changed function: BrushSizeButtonGroup
        function BrushSizeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.BrushSizeButtonGroup.SelectedObject;
            if app.smallButton.Value
                app.brush_size=-1;
            elseif app.bigButton.Value
                app.brush_size=1;
            else
                app.brush_size=0;
            end
            setBrushSizeFcn(app);
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            setAxesGridFcn(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            if app.grid_scale==-1
                grid_num=app.grid_sparse;
            elseif app.grid_scale==1
                grid_num=app.grid_dense;
            else
                grid_num=app.grid_normal;
            end
            [filename,filepath]=uigetfile('.\figures\*.bmp','Please select a binary bitmap!');
            if isequal(filename,0)
                return;
            end
            setAxesGridFcn(app);
            path=[filepath,filename];
            img_src=imread(path);
            app.image_matrix=LoadBinaryImage(img_src,grid_num);
            % draw image from matrix
            [index_x,index_y]=find(app.image_matrix==0);
            for i=1:1:length(index_x)
                rect=[index_y(i)-1,grid_num-index_x(i),1,1];
                rectangle(app.UIAxes,'Position',rect,'FaceColor','k','EdgeColor','none');
            end
        end

        % Button pushed function: BuildingButton
        function BuildingButtonPushed(app, event)
            gray_matrix=BuildFormShape(app.image_matrix,app.gray_level);
            if isempty(gray_matrix)
                return;
            end
            % square the image matrix
            [rn,cn]=size(gray_matrix);
            if rn>cn
                delta=rn-cn;
                half=floor(delta/2);
                gray_matrix=[ones(rn,half),gray_matrix,ones(rn,delta-half)];
            else
                delta=cn-rn;
                half=floor(delta/2);
                gray_matrix=[ones(half,cn);gray_matrix;ones(delta-half,cn)];
            end
            % draw image from matrix
            fig=uifigure;
            ax=uiaxes(fig);            
            grid_num=length(gray_matrix(1,:));
            gray=unique(gray_matrix);
            for index=1:1:app.gray_level
                [index_x,index_y]=find(gray_matrix==gray(index));
                for i=1:1:length(index_x)
                    rect=[index_y(i)-1,grid_num-index_x(i),1,1];
                    rectangle(ax,'Position',rect,'FaceColor',[gray(index),gray(index),gray(index)],'EdgeColor','none');
                end
            end
            % set properties of uifigure
            cen_x=app.scn_width/2;
            cen_y=app.scn_height/2;
            delta=700;
            fig.Position=[cen_x-delta/2,cen_y-delta/2,delta,delta];
            fig.Resize='off';
            fig.Name='Gray Image of the Binary Bitmap';
            ax.Position=[10,10,delta-20,delta-20];
            ax.XLim=[0,grid_num];
            ax.YLim=[0,grid_num];
            ax.XTick=0:1:grid_num;
            ax.YTick=0:1:grid_num;
            ax.XTickLabel={};
            ax.YTickLabel={};
            ax.Box='on';
            ax.XGrid='on';
            ax.YGrid='on';
            ax.Layer='top';
            ax.Toolbar.Visible='off';
            ax.Interactions=[];
            ax.GridColor=[0.8,0.8,0.8];
            ax.LineWidth=0.5;
        end

        % Value changed function: SettingButton
        function SettingButtonValueChanged(app, event)
            %value = app.SettingButton.Value;
            app.SettingButton.Value=false;
            app.gray_level=app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FormationShapeGeneratorUIFigure = uifigure('Visible', 'off');
            app.FormationShapeGeneratorUIFigure.Name = 'Formation Shape Generator';
            app.FormationShapeGeneratorUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @FormationShapeGeneratorUIFigureWindowButtonDown, true);
            app.FormationShapeGeneratorUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @FormationShapeGeneratorUIFigureWindowButtonUp, true);
            app.FormationShapeGeneratorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @FormationShapeGeneratorUIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.FormationShapeGeneratorUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.GridScaleButtonGroup = uibuttongroup(app.FormationShapeGeneratorUIFigure);
            app.GridScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GridScaleButtonGroupSelectionChanged, true);
            app.GridScaleButtonGroup.Title = 'Grid Scale';
            app.sparseButton = uiradiobutton(app.GridScaleButtonGroup);
            app.sparseButton.Tag = '-1';
            app.sparseButton.Text = 'sparse';
            app.sparseButton.Value = true;
            app.normalButton = uiradiobutton(app.GridScaleButtonGroup);
            app.normalButton.Tag = '0';
            app.normalButton.Text = 'normal';
            app.denseButton = uiradiobutton(app.GridScaleButtonGroup);
            app.denseButton.Tag = '1';
            app.denseButton.Text = 'dense';
            app.BrushSizeButtonGroup = uibuttongroup(app.FormationShapeGeneratorUIFigure);
            app.BrushSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrushSizeButtonGroupSelectionChanged, true);
            app.BrushSizeButtonGroup.Title = 'Brush Size';
            app.smallButton = uiradiobutton(app.BrushSizeButtonGroup);
            app.smallButton.Text = 'small';
            app.smallButton.Value = true;
            app.mediumButton = uiradiobutton(app.BrushSizeButtonGroup);
            app.mediumButton.Text = 'medium';
            app.bigButton = uiradiobutton(app.BrushSizeButtonGroup);
            app.bigButton.Text = 'big';
            app.GrayLevelButtonGroup = uibuttongroup(app.FormationShapeGeneratorUIFigure);
            app.GrayLevelButtonGroup.Title = 'Gray Level';
            app.EditField = uieditfield(app.GrayLevelButtonGroup, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.SettingButton = uibutton(app.GrayLevelButtonGroup, 'state');
            app.SettingButton.ValueChangedFcn = createCallbackFcn(app, @SettingButtonValueChanged, true);
            app.SettingButton.Text = 'Setting';
            app.BrushColorButtonGroup = uibuttongroup(app.FormationShapeGeneratorUIFigure);
            app.BrushColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BrushColorButtonGroupSelectionChanged, true);
            app.BrushColorButtonGroup.Title = 'Brush Color';
            app.blackButton = uiradiobutton(app.BrushColorButtonGroup);
            app.blackButton.Text = 'black';
            app.blackButton.Value = true;
            app.whiteButton = uiradiobutton(app.BrushColorButtonGroup);
            app.whiteButton.Text = 'white';
            app.BuildingButton = uibutton(app.FormationShapeGeneratorUIFigure, 'push');
            app.BuildingButton.ButtonPushedFcn = createCallbackFcn(app, @BuildingButtonPushed, true);
            app.BuildingButton.Text = 'Building';
            app.ClearImageButton = uibutton(app.FormationShapeGeneratorUIFigure, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Clear Image';
            app.LoadImageButton = uibutton(app.FormationShapeGeneratorUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.FormationShapeGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FormationShapeGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FormationShapeGeneratorUIFigure)
        end
    end
end